classdef acouticCheckerByGFFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        snapshotGUIButton             matlab.ui.control.Button
        OutputchannelButtonGroup      matlab.ui.container.ButtonGroup
        LAndRButton                   matlab.ui.control.RadioButton
        RButton                       matlab.ui.control.RadioButton
        LButton                       matlab.ui.control.RadioButton
        SpectrumdisplayButtonGroup    matlab.ui.container.ButtonGroup
        Octave12thRadioButton         matlab.ui.control.RadioButton
        Octave6thRadioButton          matlab.ui.control.RadioButton
        RawDFTButton                  matlab.ui.control.RadioButton
        LoadCyclicData                matlab.ui.control.Button
        STOP                          matlab.ui.control.Button
        START                         matlab.ui.control.Button
        InputChannelDropDown          matlab.ui.control.DropDown
        SamplingfrequencymenuLabel_2  matlab.ui.control.Label
        SamplingFrequencyDropDown     matlab.ui.control.DropDown
        SamplingfrequencymenuLabel    matlab.ui.control.Label
        SystemStatusLabel             matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        IOdeviceselectionDropDown     matlab.ui.control.DropDown
        IOdeviceselectionLabel        matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        RecordingLamp                 matlab.ui.control.Lamp
        RecordingLampLabel            matlab.ui.control.Label
        CalibrationCh2Panel           matlab.ui.container.Panel
        CALValuedBEditField_2         matlab.ui.control.NumericEditField
        CALValuedBEditField_2Label    matlab.ui.control.Label
        MeasuredSPLDropDown_2         matlab.ui.control.DropDown
        MeasuredSPLDropDown_2Label    matlab.ui.control.Label
        CalibrateButton_2             matlab.ui.control.Button
        CalibrationCh1Panel           matlab.ui.container.Panel
        CALValuedBEditField           matlab.ui.control.NumericEditField
        CALValuedBEditFieldLabel      matlab.ui.control.Label
        MeasuredSPLDropDown           matlab.ui.control.DropDown
        MeasuredSPLDropDownLabel      matlab.ui.control.Label
        CalibrateButton               matlab.ui.control.Button
        Voicememo5sButton             matlab.ui.control.Button
        SetworkingfolderButton        matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        BPFfcSlider                   matlab.ui.control.Slider
        BPFfcSliderLabel              matlab.ui.control.Label
        SnapshotLevelButton           matlab.ui.control.Button
        bpfPowerAxes                  matlab.ui.control.UIAxes
        BandSPLAxes                   matlab.ui.control.UIAxes
        SpectrumUIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    events
        RequestDisplayUpdate
    end

    properties (Access = private)
        StreamTimer    % matlab timer for polling
        SampleRate = 48000   % Sampling frequency (Hz)
        FrameLength = 1024    % default value (must be integer in ms)
        nChannel = 1
        CyclicData     % Cyclic Audio Data, structure
        SupportedSamplingRate
        FileName
        FileFolder
        FilePath
        nChannelOut = 2
        maximumDuration = 300 % seconds
        DisplayUpdateListener  % listener handle
        BandSPLDisplay % Band spectrum display
        raw1kHzBandLevel = 0
        captured1kHzBandLevel = 0
        calibrationData
        InitialCondition
        WorkingDirectory
        VoiceMemo
    end

    properties (Access = public)
        AS             % audiostreamer object
        StreamingEnable
        streamingData
        AnalysisData
        SpectrumDisplay
        powerRespDisplay
    end

    methods (Access = private)

        function populateAudioDevices(app)
            app.CyclicData = struct;
            %app.CyclicData.numberOfItems = 0;
            app.SamplingFrequencyDropDown.Enable = 'off';
            app.InputChannelDropDown.Enable = "off";
            app.LoadCyclicData.Enable = 'off';
            app.START.Enable = 'off';
            app.STOP.Enable = 'off';
            app.StreamingEnable = false;
            try
                apr = audioPlayerRecorder;
            catch ME
                uialert(app.UIFigure, "Cannot create audioPlayerRecorder: " + ME.message, "Audio Error");
                return
            end

            % Get full‑duplex devices
            devices = getAudioDevices(apr);
            devices{end+1} = ' ---- ';

            % If no devices found, show message and populate with empty entry
            if isempty(devices)
                app.IOdeviceselectionDropDown.Items = {'<No full‑duplex devices found>'};
                app.IOdeviceselectionDropDown.Enable = 'off';
                app.StatusLabel.Text = 'No full‑duplex audio devices available';
                return
            end

            % Populate DropDown and enable it
            app.IOdeviceselectionDropDown.Items = devices(:);
            app.IOdeviceselectionDropDown.Value = app.IOdeviceselectionDropDown.Items{end};
            app.IOdeviceselectionDropDown.Enable = 'on';
            app.StatusLabel.Text = "Select IO device";
        end

        function results = SelectFileButtonPushed(app)
            [fname, fpath] = uigetfile({'*.wav';'*.aif';'*.WAV';'*.AIF'}, ...
                'Select file');
            if isequal(fname,0)
                results = 0;
                return; % user canceled
            end
            app.FileName   = fname;
            app.FileFolder = fpath;
            app.FilePath   = fullfile(fpath, fname);
            results = 1;
        end


        function results = initializeGraphics(app)
            % Spectrum plot
            fx = (0:app.SampleRate-1);
            app.AnalysisData.bpfFc = 1000;
            ltiGain = zeros(app.SampleRate,1);
            rtvGain = zeros(app.SampleRate,1) - 30;
            sdtiGain = zeros(app.SampleRate,1) - 20;
            app.SpectrumDisplay.ltiHandle = ...
                semilogx(app.SpectrumUIAxes,fx,ltiGain,"LineWidth",2);
            hold(app.SpectrumUIAxes,"on");
            app.SpectrumDisplay.rtvHandle = ...
                semilogx(app.SpectrumUIAxes,fx,rtvGain,"LineWidth",2);
            app.SpectrumDisplay.sdtiHandle = ...
                semilogx(app.SpectrumUIAxes,fx,sdtiGain,"LineWidth",2);
            axis(app.SpectrumUIAxes,[20 app.SampleRate/2 -80 20]);
            app.SpectrumDisplay.fc1kHandle = ...
                xline(app.SpectrumUIAxes,1000,"g","1000","LineWidth",3);
            grid(app.SpectrumUIAxes,"on");
            set(app.SpectrumUIAxes,"LineWidth",2)
            ylabel(app.SpectrumUIAxes,"Level (rel. dB)");
            xlabel(app.SpectrumUIAxes,"frequency (Hz)");
            title(app.SpectrumUIAxes,"  ");
            drawnow;
            % BandSPL plot
            fx = (0:app.SampleRate-1);
            signalLevel = zeros(app.SampleRate,1);
            distrbanceLevel = zeros(app.SampleRate,1) - 100;
            snapshotLevel = zeros(app.SampleRate,1) - 100;
            app.BandSPLDisplay.signalLevelHandle = ...
                semilogx(app.BandSPLAxes,fx,signalLevel,"LineWidth",2);
            hold(app.BandSPLAxes,"on");
            app.BandSPLDisplay.distrbanceLevelHandle = ...
                semilogx(app.BandSPLAxes,fx,distrbanceLevel,"LineWidth",2);
            app.BandSPLDisplay.indirectLevelHandle = ...
                semilogx(app.BandSPLAxes,fx,distrbanceLevel,"LineWidth",2);
            app.BandSPLDisplay.snapshotLevelHandle = ...
                semilogx(app.BandSPLAxes,fx,snapshotLevel,"g","LineWidth",2);
            app.BandSPLDisplay.fc1kHandle = ...
                xline(app.BandSPLAxes,1000,"g","1000","LineWidth",3);
            axis(app.BandSPLAxes,[20 app.SampleRate/2 -110 0]);
            grid(app.BandSPLAxes,"on");
            set(app.BandSPLAxes,"LineWidth",2)
            ylabel(app.BandSPLAxes,"SPL CAL.NO (dB)");
            xlabel(app.BandSPLAxes,"frequency (Hz)");
            title(app.BandSPLAxes,"  ");
            %----------- power decay
            tresp = (1:app.SampleRate)/app.SampleRate;
            app.powerRespDisplay.pResp1kHandle = ...
                plot(app.bpfPowerAxes,tresp,0*tresp,"LineWidth",2);
            hold(app.bpfPowerAxes,"on");
            app.powerRespDisplay.pRespFcHandle = ...
                plot(app.bpfPowerAxes,tresp,0*tresp,"g","LineWidth",2);
            grid(app.bpfPowerAxes,"on");
            axis(app.bpfPowerAxes,[0 1 -70 0]);
            set(app.bpfPowerAxes,"LineWidth",2)
            xlabel(app.bpfPowerAxes,"time (s)")
            ylabel(app.bpfPowerAxes,"level (dB)")
            title(app.bpfPowerAxes,"Power response")
            drawnow;
            results = 1;
        end

        function results = updateGraphics(app)
            % Spectrum display
            fs = app.SampleRate;
            fftl = app.AnalysisData.impulseLength;
            fx = (0:fftl-1)'/fftl*fs;
            app.AnalysisData.frequencyAxix = fx;
            ltiGain = zeros(fftl,1);
            indiectGain = zeros(fftl,1)-10;
            rtvGain = zeros(fftl,1) - 30;
            sdtiGain = zeros(fftl,1) - 20;
            set(app.SpectrumDisplay.ltiHandle,"Xdata",fx,"Ydata",ltiGain);
            set(app.SpectrumDisplay.rtvHandle,"Xdata",fx,"Ydata",rtvGain);
            set(app.SpectrumDisplay.sdtiHandle,"Xdata",fx,"Ydata",sdtiGain-100);
            set(app.SpectrumDisplay.sdtiHandle,"Xdata",fx,"Ydata",sdtiGain-100);
            % slider for spectrum display
            app.BPFfcSlider.Enable = "on";
            nBands = app.AnalysisData.gfStr12.nChannel;
            fcSet = app.AnalysisData.gfStr12.fcSet;
            [~, idx1kHz] = min(abs(fcSet-1000));
            app.BPFfcSlider.Limits = [1 nBands];
            app.BPFfcSlider.Value = idx1kHz;
            % BandSPL display
            signalLevel = zeros(fftl,1);
            indirectLevel = zeros(fftl,1) - 200;
            distrbanceLevel = zeros(fftl,1) - 200;
            snapshotLevel = zeros(fftl,1) - 200;
            set(app.BandSPLDisplay.signalLevelHandle,"Xdata",fx,"Ydata",signalLevel);
            set(app.BandSPLDisplay.distrbanceLevelHandle,"Xdata",fx,"Ydata",distrbanceLevel);
            set(app.BandSPLDisplay.indirectLevelHandle,"Xdata",fx,"Ydata",indirectLevel);
            set(app.BandSPLDisplay.snapshotLevelHandle,"Xdata",fx,"Ydata",snapshotLevel);
            app.SpectrumDisplay.frequencyAxis = (0:app.AnalysisData.dataLength-1)' ...
                /app.AnalysisData.dataLength*fs;
            %app.SpectrumDisplay.frequencyAxisHigh = fx*2^(1/2);
            %app.SpectrumDisplay.frequencyAxisLow = fx/2^(1/2);
            drawnow limitrate;
            results = 1;
        end

        function analysisAndUpdateGraphicsHandler(app,~,~)
            if isempty(app.AS)
                return;
            elseif ~isPlaying(app.AS)
                if ~isempty(app.StreamTimer)
                    stop(app.StreamTimer);
                else
                    pause(0.1);
                    return;
                end
                pause(0.1);
                stopHousekeeping(app);
                return;
            end
            fs = app.SampleRate;
            fxx = app.SpectrumDisplay.frequencyAxis;
            fxImpulse = app.AnalysisData.frequencyAxix;
            fcSet = app.AnalysisData.gfStr6.fcSet;
            fcSetL = fcSet*2^(-1/2);
            fcSetH = fcSet*2^(1/2);
            capturedData = read(app.AS);
            nInData = size(capturedData,1);
            cycleLength = app.streamingData.tmpBufferLength;
            sourceBuffer = app.streamingData.wholeSource;
            if app.AnalysisData.headIdx + cycleLength > ...
                    size(sourceBuffer,1)
                stop(app.StreamTimer);
                pause(0.2);
                stop(app.AS);
                stopHousekeeping(app);
                return;
            end
            sourceId = 1;
            if app.RButton.Value == 1 && app.nChannelOut == 2
                sourceId = 2;
            end
            if nInData > 0
                app.streamingData.capturedBuffer(app.AnalysisData.headIdx+(1:nInData), :)= ...
                    capturedData;
                app.AnalysisData.headIdx = app.AnalysisData.headIdx + nInData;
                if app.AnalysisData.headIdx > cycleLength
                    tmpHeadIdx = app.AnalysisData.headIdx;
                    rangeIdx = tmpHeadIdx-cycleLength+1:tmpHeadIdx;
                    if app.AnalysisData.firstTime
                        app.AnalysisData.captured = sourceBuffer(rangeIdx,sourceId);
                        app.InitialCondition.captured = ...
                            app.streamingData.capturedBuffer(rangeIdx,1);
                        app.InitialCondition.source = ...
                            sourceBuffer(rangeIdx,sourceId);
                    else
                        app.AnalysisData.captured = ...
                            app.streamingData.capturedBuffer(rangeIdx,1);
                    end
                    app.AnalysisData.source = sourceBuffer(rangeIdx,sourceId);
                    epsSpec = 1e-12;
                    capturedSpectrum = fft(app.AnalysisData.captured,app.AnalysisData.Nfft);
                    capturedPower = abs(capturedSpectrum).^2/app.AnalysisData.Nfft^2;
                    octaveBandLevel = interp1(fxx,cumsum(capturedPower),fcSetH,"linear","extrap") ...
                        - interp1(fxx,cumsum(capturedPower),fcSetL,"linear","extrap");
                    app.AnalysisData.transferFunction = ...
                        capturedSpectrum ...
                        ./fft(app.AnalysisData.source + epsSpec,app.AnalysisData.Nfft);
                    app.AnalysisData.impulseResponse = ...
                        ifft(app.AnalysisData.transferFunction,"symmetric");
                    fftl = app.AnalysisData.impulseLength;
                    impulseResp = app.AnalysisData.impulseResponse;
                    [~, idxPeak] = max(abs(impulseResp));
                    biasL = app.AnalysisData.halfDataLength;
                    ltiSpec = fft(impulseResp(1:fftl));
                    indirectSpec = fft(impulseResp((1:fftl)+idxPeak+round(fs*0.015)));
                    rtvSpec = fft(impulseResp((1:fftl)+biasL));
                    ltiPw = abs(ltiSpec).^2;
                    indirectPw = abs(indirectSpec).^2;
                    rtvPw = abs(rtvSpec).^2;
                    octaveLTIBandLvl = interp1(fxImpulse,cumsum(ltiPw),fcSetH,"linear","extrap") ...
                        - interp1(fxImpulse,cumsum(ltiPw),fcSetL,"linear","extrap");
                    octaveRTVBandLvl = interp1(fxImpulse,cumsum(rtvPw),fcSetH,"linear","extrap") ...
                        - interp1(fxImpulse,cumsum(rtvPw),fcSetL,"linear","extrap");
                    octaveIndBandLvl = interp1(fxImpulse,cumsum(indirectPw),fcSetH,"linear","extrap") ...
                        - interp1(fxImpulse,cumsum(indirectPw),fcSetL,"linear","extrap");
                    relRTVBandLvl = octaveRTVBandLvl./octaveLTIBandLvl;
                    relRTVBandLvlDB = 10*log10(relRTVBandLvl);
                    relIndBandLvl = octaveIndBandLvl./octaveLTIBandLvl;
                    relIndBandLvlDB = 10*log10(relIndBandLvl);
                    if app.RawDFTButton.Value == 1
                        frequencyAxis = app.AnalysisData.frequencyAxis;
                        ltiGain = 20*log10(abs(ltiSpec));
                        rtvGain = 20*log10(abs(rtvSpec));
                    elseif app.Octave6thRadioButton.Value == 1
                        frequencyAxis = app.AnalysisData.gfStr6.fcSet;
                        ltiGain = ...
                            10*log10(app.AnalysisData.gfStr6.filterWeight*abs(ltiSpec).^2);
                        rtvGain = ...
                            10*log10(app.AnalysisData.gfStr6.filterWeight*abs(rtvSpec).^2);
                    else
                        frequencyAxis = app.AnalysisData.gfStr12.fcSet;
                        ltiGain = ...
                            10*log10(app.AnalysisData.gfStr12.filterWeight*abs(ltiSpec).^2);
                        rtvGain = ...
                            10*log10(app.AnalysisData.gfStr12.filterWeight*abs(rtvSpec).^2);
                    end
                    set(app.SpectrumDisplay.ltiHandle,"Xdata",frequencyAxis,"Ydata",ltiGain);
                    set(app.SpectrumDisplay.rtvHandle,"Xdata",frequencyAxis,"Ydata",rtvGain);
                    octaveBandLevelDB = 10*log10(octaveBandLevel) ...
                        +app.calibrationData.ch1CalibrationDb+3;
                    set(app.BandSPLDisplay.signalLevelHandle,"Xdata",fcSet,"Ydata",octaveBandLevelDB);
                    set(app.BandSPLDisplay.distrbanceLevelHandle,"Xdata",fcSet,"Ydata", ...
                        octaveBandLevelDB+relRTVBandLvlDB);
                    set(app.BandSPLDisplay.indirectLevelHandle,"Xdata",fcSet,"Ydata", ...
                        octaveBandLevelDB+relIndBandLvlDB);
                    %relIndBandLvlDB
                    %disp([size(frequencyAxis),size(octaveBandLevelDB)])
                    %------ power decay calculation
                    resp = ifft(app.AnalysisData.fc1kHzFulterF.*ltiSpec);
                    powerResp1k = 10*log10(sum(abs(resp).^2,2));
                    powerResp1k = powerResp1k-max(powerResp1k);
                    tx = (1:fftl)/app.SampleRate;
                    set(app.powerRespDisplay.pResp1kHandle,"Xdata",tx(1:10:end),"Ydata",powerResp1k(1:10:end));
                    resp = ifft(app.AnalysisData.fcSliderFulterF.*ltiSpec);
                    powerResp1k = 10*log10(sum(abs(resp).^2,2));
                    powerResp1k = powerResp1k-max(powerResp1k);
                    set(app.powerRespDisplay.pRespFcHandle,"Xdata",tx(1:10:end),"Ydata",powerResp1k(1:10:end));
                    %set(app.powerRespDisplay.)
                    app.AnalysisData.firstTime = false;
                    drawnow limitrate;
                end
            end
        end

        function results = audioStreamingLoopSlim(app)
            cycleLength = app.streamingData.tmpBufferLength;
            sourceBuffer = app.streamingData.wholeSource;
            app.AnalysisData.Nfft = cycleLength;
            app.AnalysisData.firstTime = true;
            record(app.AS);
            play(app.AS,sourceBuffer,"non-blocking");
            app.AnalysisData.headIdx = 1;
            app.StreamTimer = timer( ...
                ExecutionMode="fixedRate", ...
                Period = 0.06, ...
                TimerFcn = @(~,~) timerProcessUpdate(app) );
            start(app.StreamTimer);
            app.SnapshotLevelButton.Enable = "on";
            results = 1;
        end

        function timerProcessUpdate(app)
            notify(app, 'RequestDisplayUpdate');
        end

        function results = updateSamplingRate(app)
            fs = app.CyclicData.samplingFrequency;
            matchdID = 0;
            %disp(app.SupportedSamplingRate);
            for id = 1:size(app.SupportedSamplingRate,1)
                tmpFs = app.SupportedSamplingRate(id);
                if tmpFs == fs;matchdID = id;end
            end
            if matchdID == 0
                results = 0;
                disp(num2str(fs) + " Hz sampling is not supported.");
                return;
            else
                app.SamplingFrequencyDropDown.Value = ...
                    app.SamplingFrequencyDropDown.Items{matchdID};
                app.SampleRate = fs;
            end
            results = 1;
        end

        function results = stopHousekeeping(app)
            try
                if isprop(app, 'StreamTimer') && ~isempty(app.StreamTimer) && isvalid(app.StreamTimer)
                    stop(app.StreamTimer);
                    delete(app.StreamTimer);
                    app.StreamTimer = [];
                end
            catch ME
                %warning("Error cleaning StreamTimer: %s", ME.message);
                disp("Error cleaning StreamTimer:" + ME.message);
            end
            app.StatusLabel.Text = 'Stopped: START or Select another file.';
            app.STOP.Enable = 'off';
            app.START.Enable = 'on';
            app.LoadCyclicData.Enable = 'on';
            app.Voicememo5sButton.Enable = "on";

            if app.nChannelOut >  1
                app.OutputchannelButtonGroup.Enable = "on";
                app.LAndRButton.Enable = "on";
                app.LButton.Enable = "on";
                app.RButton.Enable = "on";
            end
            results = 1;
        end
        
        function results = initializeWorkindDirectory(app)
            app.StatusLabel.Text = "Select where to set working directory.";
            drawnow
            while isempty(app.WorkingDirectory)
                pause(0.2); % wait for Working Directory to set
            end
            app.SetworkingfolderButton.Enable = "off";
            app.IOdeviceselectionDropDown.Enable = "on";
            app.IOdeviceselectionLabel.Enable = "on";
            results = 1;
        end
        
        function results = createAudioStreamer(app)
            results = 0;
            dev = app.IOdeviceselectionDropDown.Value;
            app.StatusLabel.Text = ['Opening: ' dev];
            switch computer
                case {'MACI64','MACA64'}
                    driverStr = 'CoreAudio';
                case 'PCWIN64'
                    driverStr = 'ASIO';
                otherwise
                    uialert(app.UIFigure, "Cannot start audioPlayerRecorder: " + ME.message, "Audio Error");
                    return
            end

            % Create audiostreamer for full-duplex
            try
                app.AS = audiostreamer("Mode","full-duplex","SampleRate",app.SampleRate, ...
                    "Player",dev,"Recorder",dev,"DeviceBitFormat","int24",...
                    "PlayerChannels",(1:app.nChannelOut),"RecorderChannels",(1:app.nChannel), ...
                    "DeviceBufferSize",app.FrameLength,"Driver",driverStr);
            catch
                uialert(app.UIFigure, "Cannot start audioPlayerRecorder: " + ME.message, "Audio Error");
                return
            end
            results = 1;
        end
        
        function results = enableCalibration(app)
            %app.calibrationData.initialized = false;
            switch app.nChannel
                case 1
                    app.calibrationData.ch1 = 0;
                    app.CalibrationCh1Panel.Enable = "on";
                    app.CalibrateButton.Enable = "on";
                    app.CALValuedBEditField.Enable = "off";
                    app.CALValuedBEditFieldLabel.Enable = "off";
                case 2
                    app.calibrationData.ch1 = 0;
                    app.CalibrationCh1Panel.Enable = "on";
                    app.CalibrateButton.Enable = "on";
                    app.CALValuedBEditField.Enable = "off";
                    app.CALValuedBEditFieldLabel.Enable = "off";
                    app.calibrationData.ch2 = 0;
                    app.CalibrationCh2Panel.Enable = "on";
                    app.CalibrateButton_2.Enable = "on";
                    app.CALValuedBEditField_2.Enable = "off";
                    app.CALValuedBEditField_2Label.Enable = "off";
                otherwise
                    app.calibrationData.ch1 = 0; %safeguard
                    return;
            end
            app.calibrationData.initialized = true;
            results = 1;
        end
        
        function results = generateCalibrationSignal(app)
            tic;
            fs = app.SampleRate;
            tt = (1:fs)'/fs;
            fc = 1000; % center frequency
            fcList = round(fc*2^(-1/2)):round(fc*2^(1/2));
            x = zeros(fs,1);
            for ii = 1:length(fcList)
                x = x + sin(2*pi*(fcList(ii)*tt+rand));
            end
            unitSource = x/std(x)*0.1;
            sz = size(unitSource);
            tmpBuffer = [unitSource unitSource];
            if sz(2) < app.nChannelOut
                if app.LAndRButton.Value == 1
                    % do nothing
                elseif app.LButton.Value == 1
                    tmpBuffer = tmpBuffer*[1 0;0 0];
                elseif app.RButton.Value == 1
                    tmpBuffer = tmpBuffer*[0 0;0 1];
                end
            end
            nCycles = floor(app.maximumDuration*fs/sz(1));
            x = zeros(nCycles*sz(1),app.nChannelOut);
            for ii = 1:nCycles
                x((ii-1)*sz(1)+(1:sz(1)),:) = tmpBuffer;
            end
            toc
            app.calibrationData.calibrationSignal = x;
            results = 1;
        end
        
        
        function results = designFcOcatveSet(app)
            fc = app.AnalysisData.bpfFc;
            fcSet = app.AnalysisData.gfStr12.fcSet;
            [~, idxFc] = min(abs(fc-fcSet));
            app.AnalysisData.tmpBPFset = ...
                app.AnalysisData.gfStr12.quadfilterWeight(:,idxFc+(-11:11));
            results = 1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide the axes toolbar on the UIAxes
            app.SpectrumUIAxes.Toolbar.Visible = 'off';
            app.DisplayUpdateListener = addlistener(app, 'RequestDisplayUpdate', ...
                @(src,ev) analysisAndUpdateGraphicsHandler(app, src, ev));
            % Disable built-in interactions for this axes (pan, zoom, datatip, region zoom...)
            %disableDefaultInteractivity(app.SpectrumUIAxes);
            app.calibrationData.initialized = false;
            app.calibrationData.ch1CalibrationDb = 0;
            app.calibrationData.ch2CalibrationDb = 0;
            app.WorkingDirectory = [];
            initializeWorkindDirectory(app);
            initializeGraphics(app);
            populateAudioDevices(app);
        end

        % Value changed function: IOdeviceselectionDropDown
        function IOdeviceselectionDropDownValueChanged(app, event)
            selected = app.IOdeviceselectionDropDown.Value;
            % Example: show selection in a label
            app.StatusLabel.Text = "Selected: " + selected;
            %disp(selected);
            candidateRates = [8000 11025 16000 22050 32000 44100 48000 88200 96000 192000];
            supported = zeros(length(candidateRates),1);
            ii = 0;
            for r = candidateRates
                try
                    % Try to create an audiostreamer in full-duplex with this rate
                    as = audiostreamer("full-duplex", r, ...
                        Player = selected, ...
                        Recorder = selected);
                    tmpOutCh = as.MaxPlayerChannels;
                    release(as);    % release if created
                    ii = ii + 1;
                    supported(ii) = r;
                catch
                    disp(r);
                    % not supported at this rate or cannot open device with this configuration
                end
            end
            supported = supported(1:ii);
            app.SupportedSamplingRate = supported;
            supportedStringArray = string(num2str(supported))';
            supportedStringArray{end+1} = ' ---- ';
            app.SamplingFrequencyDropDown.Items = supportedStringArray;
            app.SamplingFrequencyDropDown.Value = app.SamplingFrequencyDropDown.Items{end};
            app.SamplingFrequencyDropDown.Enable = 'on';
            app.IOdeviceselectionDropDown.Enable = "off";
            if tmpOutCh > 1
                app.nChannelOut = 2; % up to stereo
                app.OutputchannelButtonGroup.Enable = "on";
                app.LAndRButton.Enable = "on";
                app.LButton.Enable = "on";
                app.RButton.Enable = "on";
            end
            app.StatusLabel.Text = "Select Sampling frequency";
        end

        % Value changed function: SamplingFrequencyDropDown
        function SamplingFrequencyDropDownValueChanged(app, event)
            value = app.SamplingFrequencyDropDown.Value;
            selectedDevice = app.IOdeviceselectionDropDown.Value;
            r = str2double(value);
            app.AS = audiostreamer("full-duplex", r, ...
                Player = selectedDevice, ...
                Recorder = selectedDevice);
            app.StatusLabel.Text = "Sampling frequency is "+value+" Hz";
            app.SampleRate = r;
            pause(0.5);
            app.SamplingFrequencyDropDown.Enable = "off";
            app.StatusLabel.Text = "Select Number of Input Channels";
            if app.AS.MaxRecorderChannels > 1
                app.InputChannelDropDown.Enable = "on";
            else
                app.InputChannelDropDown.Value = app.InputChannelDropDown.Items{1};
                app.InputChannelDropDown.Enable = "off";
                InputChannelDropDownValueChanged(app, event)
            end
            release(app.AS);
        end

        % Value changed function: InputChannelDropDown
        function InputChannelDropDownValueChanged(app, event)
            itemId = app.InputChannelDropDown.ValueIndex;% ;str2double(app.InputChannelDropDown.Value);
            app.nChannel = str2double(app.InputChannelDropDown.ItemsData(itemId));
            if app.nChannel > 0
                app.StatusLabel.Text = "Number of Input Channels: " + app.InputChannelDropDown.Value;
                pause(0.2);
                app.InputChannelDropDown.Enable = "off";
                app.StatusLabel.Text = "Ready to start";
                app.LoadCyclicData.Enable = 'on';
                app.SpectrumdisplayButtonGroup.Enable = "on";
                app.Octave12thRadioButton.Enable = "on";
                app.Octave6thRadioButton.Enable = "on";
                app.RawDFTButton.Enable = "on";
                app.Voicememo5sButton.Enable = "on";
            end
        end

        % Button pushed function: START
        function STARTButtonPushed(app, event)
            app.Voicememo5sButton.Enable = "off";
            if 1 == 2
            dev = app.IOdeviceselectionDropDown.Value;
            app.StatusLabel.Text = ['Opening: ' dev];
            switch computer
                case {'MACI64','MACA64'}
                    driverStr = 'CoreAudio';
                case 'PCWIN64'
                    driverStr = 'ASIO';
                otherwise
                    uialert(app.UIFigure, "Cannot start audioPlayerRecorder: " + ME.message, "Audio Error");
                    return
            end

            % Create audiostreamer for full-duplex
            try
                app.AS = audiostreamer("Mode","full-duplex","SampleRate",app.SampleRate, ...
                    "Player",dev,"Recorder",dev,"DeviceBitFormat","int24",...
                    "PlayerChannels",(1:app.nChannelOut),"RecorderChannels",(1:app.nChannel), ...
                    "DeviceBufferSize",app.FrameLength,"Driver",driverStr);
            catch
                uialert(app.UIFigure, "Cannot start audioPlayerRecorder: " + ME.message, "Audio Error");
                return
            end
            end
            if createAudioStreamer(app) == 0
                return;
            end

            % Set timer environment
            app.streamingData.headIndex = 1;
            app.streamingData.playIdx = 1;
            app.StatusLabel.Text = "Streaming ";
            app.StreamingEnable = true;
            fs = app.SampleRate;
            app.streamingData.capturedBuffer = ...
                zeros(fs*app.maximumDuration,app.nChannel);
            unitSource = app.CyclicData.data;
            sz = size(unitSource);
            tmpBuffer = [unitSource unitSource];
            if sz(2) < app.nChannelOut
                if app.LAndRButton.Value == 1
                    % do nothing
                elseif app.LButton.Value == 1
                    tmpBuffer = tmpBuffer*[1 0;0 0];
                elseif app.RButton.Value == 1
                    tmpBuffer = tmpBuffer*[0 0;0 1];
                end
            end
            nCycles = floor(app.maximumDuration*fs/sz(1));
            app.streamingData.wholeSource = zeros(nCycles*sz(1),app.nChannelOut);
            for ii = 1:nCycles
                app.streamingData.wholeSource((ii-1)*sz(1)+(1:sz(1)),:) = ...
                    tmpBuffer;
            end
            app.streamingData.tmpBuffer = tmpBuffer;
            app.streamingData.tmpBufferLength = size(tmpBuffer,1);
            app.streamingData.total_numUnderrun = 0;
            app.streamingData.total_numOverrun = 0;
            % Create and start IO loop
            app.START.Enable = 'off';
            app.STOP.Enable = 'on';
            app.LoadCyclicData.Enable = 'off';
            app.OutputchannelButtonGroup.Enable = "off";
            app.LAndRButton.Enable = "off";
            app.LButton.Enable = "off";
            app.RButton.Enable = "off";

            % This naming is misleading. This starts timer
            audioStreamingLoopSlim(app);
        end

        % Button pushed function: STOP
        function STOPButtonPushed(app, event)
            app.StreamingEnable = false;
            stop(app.StreamTimer);
            stop(app.AS);
            pause(0.2);
            app.SnapshotLevelButton.Enable = "off";
            if ~app.calibrationData.initialized
                enableCalibration(app);
            end
            stopHousekeeping(app);
            tstr = datetime(datetime,'format','yyyyMMddHHmmss');
            %---------
            fNameSource = "initialSourceAT"+string(tstr)+"r.wav";
            fNameCaptured = "initialCapturedAT"+string(tstr)+"r.wav";
            sourceFullpath = app.WorkingDirectory.fullpath+"/"+fNameSource;
            capturedFullpath = app.WorkingDirectory.fullpath+"/"+fNameCaptured;
            audiowrite(sourceFullpath,app.InitialCondition.source,...
                app.SampleRate,"BitsPerSample",24,'Title',app.FileName);
            audiowrite(capturedFullpath,app.InitialCondition.captured,...
                app.SampleRate,"BitsPerSample",24,'Title',app.FileName);
            %----------
            headIdx = app.AnalysisData.headIdx;
            sourceData = app.streamingData.wholeSource(1:headIdx,:);
            capturedData = app.streamingData.capturedBuffer(1:headIdx,:);
            fNameSource = "sourceAT"+string(tstr)+"r.wav";
            fNameCaptured = "capturedAT"+string(tstr)+"r.wav";
            sourceFullpath = app.WorkingDirectory.fullpath+"/"+fNameSource;
            capturedFullpath = app.WorkingDirectory.fullpath+"/"+fNameCaptured;
            audiowrite(sourceFullpath,sourceData,...
                app.SampleRate,"BitsPerSample",24,'Title',app.FileName);
            audiowrite(capturedFullpath,capturedData,...
                app.SampleRate,"BitsPerSample",24,'Title',app.FileName);
        end

        % Button pushed function: LoadCyclicData
        function LoadCyclicDataButtonPushed(app, event)
            results = SelectFileButtonPushed(app);
            if results == 0
                return;
            end
            %app.CyclicData.numberOfItems = app.CyclicData.numberOfItems+1;
            [x,fs] = audioread(app.FilePath);
            app.CyclicData.name = app.FileName;
            app.CyclicData.data = x;
            app.CyclicData.samplingFrequency = fs;
            if app.SampleRate ~= fs
                results = updateSamplingRate(app);
                if results == 0
                    app.StatusLabel.Text = "This samplingRate is not supported. Select another file. ";
                    return;
                end
            end
            %disp(app.CyclicData);
            title(app.SpectrumUIAxes,"File: "+app.FileName,"Interpreter","none");
            app.AnalysisData.impulseLength = min(fs,floor(size(x,1)/3));
            app.AnalysisData.dataLength = size(x,1);
            app.AnalysisData.halfDataLength = floor(size(x,1)/2);
            app.AnalysisData.frequencyAxis = ...
                (0:app.AnalysisData.impulseLength-1)/app.AnalysisData.impulseLength*fs;
            frequencyAxis = app.AnalysisData.frequencyAxis;
            app.AnalysisData.gfStr6 = ...
                constantQgaussBPFweight(frequencyAxis, 1/3, 1/24);
            app.AnalysisData.gfStr12 = ...
                constantQgaussBPFweight(frequencyAxis, 1/12, 1/24);
            app.AnalysisData.bpfFc = 1000;
            designFcOcatveSet(app);
            app.AnalysisData.fc1kHzFulterF = app.AnalysisData.tmpBPFset;
            app.AnalysisData.fcSliderFulterF = app.AnalysisData.tmpBPFset;
            updateGraphics(app);
            app.StatusLabel.Text = "Redy to START ";
            app.START.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % UIFIGURECLOSEREQUEST  Safe cleanup when app window is closed.

            % Stop & delete app timer (only the timer owned by the app)
            try
                if isprop(app, 'StreamTimer') && ~isempty(app.StreamTimer) && isvalid(app.StreamTimer)
                    stop(app.StreamTimer);
                    delete(app.StreamTimer);
                    app.StreamTimer = [];
                end
            catch ME
                %warning("Error cleaning StreamTimer: %s", ME.message);
                disp("Error cleaning StreamTimer:" + ME.message);
            end

            % Stop and release audiostreamer (or audioDeviceReader/Writer) if present
            try
                % Prefer isprop + isempty; some releases don't support isvalid for custom objects
                if isprop(app, 'AS') && ~isempty(app.AS)
                    % If object supports isrunning/isvalid, guard calls
                    try
                        if ismethod(app.AS, 'isRunning') && app.AS.isRunning()
                            stop(app.AS);
                        elseif ismethod(app.AS, 'isRunning') && ~app.AS.isRunning()
                            % already stopped
                        else
                            % try stop anyway
                            stop(app.AS);
                        end
                    catch
                        % stop may throw for unsupported objects; ignore and try release
                        try stop(app.AS); catch, end
                    end

                    % release if supported
                    try
                        if ismethod(app.AS, 'release')
                            release(app.AS);
                        end
                    catch
                        % ignore release errors
                    end

                    % clear reference
                    app.AS = [];
                end
            catch ME
                %warning("Error cleaning audio stream object: %s", ME.message);
                disp("Error cleaning audio stream object:" + ME.message);
            end

            % Delete any capture buffers or large data to free memory
            try
                if isprop(app, 'streamingData') && ~isempty(app.streamingData)
                    app.streamingData = [];
                end
            catch
                % ignore
            end

            % Finally delete the app (this closes the UIFigure)
            try
                delete(app);
            catch ME
                % If delete failed, fallback to deleting the UIFigure only
                try
                    if isprop(app, 'UIFigure') && isvalid(app.UIFigure)
                        delete(app.UIFigure);
                    end
                catch
                    %warning("Error closing app window: %s", ME.message);
                    disp("Error closing app window:" + ME.message);
                end
            end
        end

        % Button pushed function: SnapshotLevelButton
        function SnapshotLevelButtonPushed(app, event)
            %set(app.BandSPLDisplay.signalLevel,"Xdata",fcSet,"Ydata",octaveBandLevelDB);
            xData = app.BandSPLDisplay.signalLevelHandle.XData;
            yData = app.BandSPLDisplay.signalLevelHandle.YData;
            set(app.BandSPLDisplay.snapshotLevelHandle,"Xdata",xData,"Ydata",yData);
        end

        % Button pushed function: SetworkingfolderButton
        function SetworkingfolderButtonPushed(app, event)
            selpath = uigetdir;
            if selpath == 0
                return
            else
                app.WorkingDirectory.base = selpath;
                tstr = datetime(datetime,'format','yyyyMMddHHmmss');
                workindDirectoryName = "acTest" + string(tstr);
                app.WorkingDirectory.name = workindDirectoryName;
                mkdir(selpath+"/"+workindDirectoryName);
                app.WorkingDirectory.fullpath = selpath + "/" + workindDirectoryName;
                app.IOdeviceselectionDropDown.Enable = "on";
                app.snapshotGUIButton.Enable = "on";
            end
        end

        % Button pushed function: Voicememo5sButton
        function Voicememo5sButtonPushed(app, event)
            if createAudioStreamer(app) == 0
                return;
            else
                xSilence = zeros(app.SampleRate*5,app.nChannelOut);
                record(app.AS);
                play(app.AS,xSilence,"non-blocking");
                backUpMessage = app.StatusLabel.Text;
                startTime = tic;
                while toc(startTime) <= 5.3
                    pause(0.1)
                    if isPlaying(app.AS)
                        app.RecordingLamp.Enable = "on";
                        app.RecordingLampLabel.Enable = "on";
                        timeLeft = round((5-toc(startTime))*10)/10;
                        app.StatusLabel.Text = "Voice memo: " + num2str(timeLeft) + " (s) left";
                        drawnow limitrate
                    end
                end
                app.StatusLabel.Text = backUpMessage;
                app.RecordingLamp.Enable = "off";
                app.RecordingLampLabel.Enable = "off";
                y = read(app.AS);
                app.VoiceMemo.data = y;
                app.VoiceMemo.SamplingRate = app.SampleRate;
                stop(app.AS);
                pause(0.2);
                release(app.AS);
                tstr = datetime(datetime,'format','yyyyMMddHHmmss');
                fNameVoicememo = "voiceMemo"+string(tstr)+"r.wav";
                voicememoFullpath = app.WorkingDirectory.fullpath+"/"+fNameVoicememo;
                audiowrite(voicememoFullpath,y/max(abs(y(:)))*0.5,...
                    app.SampleRate,"BitsPerSample",24,'Title','VoiceMemo');
            end
        end

        % Button pushed function: snapshotGUIButton
        function snapshotGUIButtonPushed(app, event)
            frameImage = getframe(app.UIFigure);     % capture frame
            img = frame2im(frameImage);              % convert to image array
            tstr = datetime(datetime,'format','yyyyMMddHHmmss');
            fName = "GUIsnapAT" + string(tstr) + ".png";
            fullPathName = app.WorkingDirectory.fullpath+"/"+ fName;
            imwrite(img, fullPathName);
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            splLevels = [0,95:-1:65];
            fs = app.SampleRate;
            supportedStringArray = string(num2str(splLevels'))';
            app.MeasuredSPLDropDown.Items = supportedStringArray;
            app.MeasuredSPLDropDown.Value = app.MeasuredSPLDropDown.Items{1};
            
            app.MeasuredSPLDropDownLabel.Enable = "on";
            generateCalibrationSignal(app);
            if createAudioStreamer(app) == 0
                return;
            else
                record(app.AS);
                play(app.AS,app.calibrationData.calibrationSignal,"non-blocking");
                backUpMessage = app.StatusLabel.Text;
                startTime = tic;
                while isPlaying(app.AS)
                    pause(0.1)
                    app.RecordingLamp.Enable = "on";
                    app.RecordingLampLabel.Enable = "on";
                    if toc(startTime) > 3.1
                        app.MeasuredSPLDropDown.Enable = "on";
                    end
                    drawnow limitrate
                end
                y = read(app.AS);
                %stop(app.AS);
                pause(0.2);
                release(app.AS);
                observedSTDdB = 20*log10(std(y(end-3*fs:end,1)));
                app.CALValuedBEditField.Value = ...
                    app.calibrationData.ch1Measured - observedSTDdB;
                app.calibrationData.ch1CalibrationDb = ...
                    app.calibrationData.ch1Measured - observedSTDdB;
                app.RecordingLamp.Enable = "off";
                app.RecordingLampLabel.Enable = "off";
                app.CalibrateButton.Enable = "off";
                app.MeasuredSPLDropDown.Enable = "off";
                app.MeasuredSPLDropDownLabel.Enable = "off";
                app.StatusLabel.Text = backUpMessage;
                drawnow
            end
        end

        % Value changed function: MeasuredSPLDropDown
        function MeasuredSPLDropDownValueChanged(app, event)
            value = app.MeasuredSPLDropDown.Value;
            app.calibrationData.ch1Measured = str2double(value);
            app.BandSPLAxes.YLim = [0 100];
            app.BandSPLAxes.YLabel.String = "CAL. SPL (dB)";
            stop(app.AS);
        end

        % Value changed function: BPFfcSlider
        function BPFfcSliderValueChanged(app, event)
            value = app.BPFfcSlider.Value;
            %disp(value);
            fcSet = app.AnalysisData.gfStr12.fcSet;
            fc = fcSet(value);
            app.AnalysisData.bpfFc = fc;
            app.SpectrumDisplay.fc1kHandle.Value = fc;
            app.SpectrumDisplay.fc1kHandle.Label = num2str(fc,"%6.0f");
            app.BandSPLDisplay.fc1kHandle.Value = fc;
            app.BandSPLDisplay.fc1kHandle.Label = num2str(fc,"%6.0f");
            app.AnalysisData.bpfFc = fc;
            designFcOcatveSet(app);
            app.AnalysisData.fcSliderFulterF = app.AnalysisData.tmpBPFset;
        end

        % Value changing function: BPFfcSlider
        function BPFfcSliderValueChanging(app, event)
            changingValue = event.Value;
            fcSet = app.AnalysisData.gfStr12.fcSet;
            fc = fcSet(changingValue);
            app.BPFfcSliderLabel.Text = num2str(fc,"%7.0f");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {665, 665, 665};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {665, 665};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {289, '1x', 658};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IOdeviceselectionLabel = uilabel(app.LeftPanel);
            app.IOdeviceselectionLabel.Enable = 'off';
            app.IOdeviceselectionLabel.Text = 'IO device selection';
            app.IOdeviceselectionDropDown = uidropdown(app.LeftPanel);
            app.IOdeviceselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @IOdeviceselectionDropDownValueChanged, true);
            app.IOdeviceselectionDropDown.Enable = 'off';
            app.StatusLabel = uilabel(app.LeftPanel);
            app.StatusLabel.Text = 'StatusLabel';
            app.SystemStatusLabel = uilabel(app.LeftPanel);
            app.SystemStatusLabel.Text = 'System Status';
            app.SamplingfrequencymenuLabel = uilabel(app.LeftPanel);
            app.SamplingfrequencymenuLabel.Enable = 'off';
            app.SamplingfrequencymenuLabel.Text = 'Sampling frequency';
            app.SamplingFrequencyDropDown = uidropdown(app.LeftPanel);
            app.SamplingFrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyDropDownValueChanged, true);
            app.SamplingFrequencyDropDown.Enable = 'off';
            app.SamplingfrequencymenuLabel_2 = uilabel(app.LeftPanel);
            app.SamplingfrequencymenuLabel_2.Enable = 'off';
            app.SamplingfrequencymenuLabel_2.Text = 'Input Channels';
            app.InputChannelDropDown = uidropdown(app.LeftPanel);
            app.InputChannelDropDown.Items = {'1', '2', '0'};
            app.InputChannelDropDown.ItemsData = {'1', '2', '0'};
            app.InputChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @InputChannelDropDownValueChanged, true);
            app.InputChannelDropDown.Enable = 'off';
            app.InputChannelDropDown.Value = '0';
            app.START = uibutton(app.LeftPanel, 'push');
            app.START.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.START.Enable = 'off';
            app.START.Text = 'START';
            app.STOP = uibutton(app.LeftPanel, 'push');
            app.STOP.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOP.Enable = 'off';
            app.STOP.Text = 'STOP';
            app.LoadCyclicData = uibutton(app.LeftPanel, 'push');
            app.LoadCyclicData.ButtonPushedFcn = createCallbackFcn(app, @LoadCyclicDataButtonPushed, true);
            app.LoadCyclicData.Enable = 'off';
            app.LoadCyclicData.Text = 'Load cyclic data';
            app.SpectrumdisplayButtonGroup = uibuttongroup(app.LeftPanel);
            app.SpectrumdisplayButtonGroup.Enable = 'off';
            app.SpectrumdisplayButtonGroup.Title = 'Spectrum display';
            app.RawDFTButton = uiradiobutton(app.SpectrumdisplayButtonGroup);
            app.RawDFTButton.Enable = 'off';
            app.RawDFTButton.Text = 'Raw DFT ';
            app.RawDFTButton.Value = true;
            app.Octave6thRadioButton = uiradiobutton(app.SpectrumdisplayButtonGroup);
            app.Octave6thRadioButton.Enable = 'off';
            app.Octave6thRadioButton.Text = '1/3 octave smoothed';
            app.Octave12thRadioButton = uiradiobutton(app.SpectrumdisplayButtonGroup);
            app.Octave12thRadioButton.Enable = 'off';
            app.Octave12thRadioButton.Text = '1/12 octave smoothed';
            app.OutputchannelButtonGroup = uibuttongroup(app.LeftPanel);
            app.OutputchannelButtonGroup.Enable = 'off';
            app.OutputchannelButtonGroup.Title = 'Output channel';
            app.LButton = uiradiobutton(app.OutputchannelButtonGroup);
            app.LButton.Enable = 'off';
            app.LButton.Text = 'L';
            app.LButton.Value = true;
            app.RButton = uiradiobutton(app.OutputchannelButtonGroup);
            app.RButton.Enable = 'off';
            app.RButton.Text = 'R';
            app.LAndRButton = uiradiobutton(app.OutputchannelButtonGroup);
            app.LAndRButton.Enable = 'off';
            app.LAndRButton.Text = 'L and R';
            app.snapshotGUIButton = uibutton(app.LeftPanel, 'push');
            app.snapshotGUIButton.ButtonPushedFcn = createCallbackFcn(app, @snapshotGUIButtonPushed, true);
            app.snapshotGUIButton.Enable = 'off';
            app.snapshotGUIButton.Text = 'snapshot GUI';
            app.CenterPanel = uipanel(app.GridLayout);
            app.SetworkingfolderButton = uibutton(app.CenterPanel, 'push');
            app.SetworkingfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetworkingfolderButtonPushed, true);
            app.SetworkingfolderButton.Text = 'Set working folder';
            app.Voicememo5sButton = uibutton(app.CenterPanel, 'push');
            app.Voicememo5sButton.ButtonPushedFcn = createCallbackFcn(app, @Voicememo5sButtonPushed, true);
            app.Voicememo5sButton.Enable = 'off';
            app.Voicememo5sButton.Text = 'Voice memo 5s';
            app.CalibrationCh1Panel = uipanel(app.CenterPanel);
            app.CalibrationCh1Panel.Enable = 'off';
            app.CalibrationCh1Panel.Title = 'Calibration Ch-1';
            app.CalibrateButton = uibutton(app.CalibrationCh1Panel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.MeasuredSPLDropDownLabel = uilabel(app.CalibrationCh1Panel);
            app.MeasuredSPLDropDownLabel.Enable = 'off';
            app.MeasuredSPLDropDownLabel.Text = 'Measured SPL';
            app.MeasuredSPLDropDown = uidropdown(app.CalibrationCh1Panel);
            app.MeasuredSPLDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasuredSPLDropDownValueChanged, true);
            app.MeasuredSPLDropDown.Enable = 'off';
            app.CALValuedBEditFieldLabel = uilabel(app.CalibrationCh1Panel);
            app.CALValuedBEditFieldLabel.Enable = 'off';
            app.CALValuedBEditFieldLabel.Text = 'CAL. Value: dB';
            app.CALValuedBEditField = uieditfield(app.CalibrationCh1Panel, 'numeric');
            app.CALValuedBEditField.ValueDisplayFormat = '%9.1f';
            app.CALValuedBEditField.Enable = 'off';
            app.CalibrationCh2Panel = uipanel(app.CenterPanel);
            app.CalibrationCh2Panel.Enable = 'off';
            app.CalibrationCh2Panel.Title = 'Calibration Ch-2';
            app.CalibrateButton_2 = uibutton(app.CalibrationCh2Panel, 'push');
            app.CalibrateButton_2.Enable = 'off';
            app.CalibrateButton_2.Text = 'Calibrate';
            app.MeasuredSPLDropDown_2Label = uilabel(app.CalibrationCh2Panel);
            app.MeasuredSPLDropDown_2Label.Enable = 'off';
            app.MeasuredSPLDropDown_2Label.Text = 'Measured SPL';
            app.MeasuredSPLDropDown_2 = uidropdown(app.CalibrationCh2Panel);
            app.MeasuredSPLDropDown_2.Enable = 'off';
            app.CALValuedBEditField_2Label = uilabel(app.CalibrationCh2Panel);
            app.CALValuedBEditField_2Label.Enable = 'off';
            app.CALValuedBEditField_2Label.Text = 'CAL. Value: dB';
            app.CALValuedBEditField_2 = uieditfield(app.CalibrationCh2Panel, 'numeric');
            app.CALValuedBEditField_2.ValueDisplayFormat = '%9.1f';
            app.CALValuedBEditField_2.Enable = 'off';
            app.RecordingLampLabel = uilabel(app.CenterPanel);
            app.RecordingLampLabel.Enable = 'off';
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.CenterPanel);
            app.RecordingLamp.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.SpectrumUIAxes = uiaxes(app.RightPanel);
            title(app.SpectrumUIAxes, 'Title')
            xlabel(app.SpectrumUIAxes, 'X')
            ylabel(app.SpectrumUIAxes, 'Y')
            zlabel(app.SpectrumUIAxes, 'Z')
            app.BandSPLAxes = uiaxes(app.RightPanel);
            title(app.BandSPLAxes, 'Title')
            xlabel(app.BandSPLAxes, 'X')
            ylabel(app.BandSPLAxes, 'Y')
            zlabel(app.BandSPLAxes, 'Z')
            app.bpfPowerAxes = uiaxes(app.RightPanel);
            title(app.bpfPowerAxes, 'Title')
            xlabel(app.bpfPowerAxes, 'X')
            ylabel(app.bpfPowerAxes, 'Y')
            zlabel(app.bpfPowerAxes, 'Z')
            app.SnapshotLevelButton = uibutton(app.RightPanel, 'push');
            app.SnapshotLevelButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotLevelButtonPushed, true);
            app.SnapshotLevelButton.Enable = 'off';
            app.SnapshotLevelButton.Text = 'Snapshot Level';
            app.BPFfcSliderLabel = uilabel(app.RightPanel);
            app.BPFfcSliderLabel.Text = 'BPF fc';
            app.BPFfcSlider = uislider(app.RightPanel);
            app.BPFfcSlider.MajorTicks = [];
            app.BPFfcSlider.MajorTickLabels = {''};
            app.BPFfcSlider.ValueChangedFcn = createCallbackFcn(app, @BPFfcSliderValueChanged, true);
            app.BPFfcSlider.ValueChangingFcn = createCallbackFcn(app, @BPFfcSliderValueChanging, true);
            app.BPFfcSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.BPFfcSlider.Step = 1;
            app.BPFfcSlider.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = acouticCheckerByGFFT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end